classdef HistoLens_WSI_Annotation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WSIAnnotationUIFigure        matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        StructuresPanel              matlab.ui.container.Panel
        GridLayout5                  matlab.ui.container.GridLayout
        SlideTable                   matlab.ui.control.Table
        HistoLensWSIAnnotationLabel  matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        GridLayout4                  matlab.ui.container.GridLayout
        NextButton                   matlab.ui.control.Button
        PreviousButton               matlab.ui.control.Button
        GridLayout3                  matlab.ui.container.GridLayout
        SelectFolderButton           matlab.ui.control.Button
        WSIFolderLabel               matlab.ui.control.Label
        WSIAxes                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        WSI_Folder
        Slide_List
        Slide_Idx
        Current_Img
        Current_Mask
        Annotation_Format = 1
        Structure_Properties

        WSI_Formats = {'.svs','.tif','.tiff','.scn','.dcm','.vms','.vmu',...
            '.ndpi','.mrxs','.svslide','.czi','.bif'};
    end
    
    methods (Access = private)
        
        function New_Image_View(app,first_run)

            axes(app.WSIAxes)
            bigimageshow(app.Current_Img,'Parent',app.WSIAxes)
            title(app.WSIAxes,app.Slide_List(app.Slide_Idx).name,'Interpreter','none')       

            % Resetting element numbers
            for st =1:length(app.Structure_Properties)
                app.Structure_Properties(st).elements = [];
            end
            
        end
        
        
        function Add_New_Structure(app,src,event,clicked_name)
            % Clicking the add structure button, opening input dialog and
            % adding the result to the structures panel as well as adding a
            % new "New Structure" button underneath

            if strcmp(clicked_name,'New Structure')

                % For "New Structure" clicked:
                prompt = {'Enter the name of the new structure: '};
                dlgtitle = 'New Structure';
                fieldsize = [1 100];
                definput = {''};
                opts.Interpreter = 'tex';
    
                new_structure = inputdlg(prompt,dlgtitle,fieldsize,definput,opts);
    
                if ~isempty(new_structure)
                    
                    new_struct_props.name = new_structure;
                    new_struct_props.color = [rand,rand,rand];
                    new_struct_props.id = strrep(char(matlab.lang.internal.uuid()),'-','');
                    new_struct_props.elements = [];

                    app.SlideTable.Data = [app.SlideTable.Data,num2cell(zeros(length(app.Slide_List),1))];
                    app.SlideTable.ColumnName = [app.SlideTable.ColumnName;new_structure];
    
                    new_structure_button = uibutton(app.GridLayout5,'push');
                    new_structure_button.Enable = 'on';
                    new_structure_button.BackgroundColor = new_struct_props.color;
                    new_structure_button.Text = new_structure(1);
                    new_structure_button.FontWeight = 'bold';
                    new_structure_button.ButtonPushedFcn = {@app.New_Annotation,new_struct_props.id};

                    new_struct_props.handle = new_structure_button;

                    if isempty(app.Structure_Properties)
                        app.Structure_Properties = [new_struct_props];
                    else
                        app.Structure_Properties = [app.Structure_Properties;new_struct_props];
                    end

                    new_structure_button.Layout.Row = length(app.Structure_Properties)+1;
                    new_structure_button.Layout.Column = 1;

                    new_structure_context_menu = uicontextmenu("Parent",app.WSIAnnotationUIFigure);
                    del_butt = uimenu(new_structure_context_menu,"Text","Delete Structure","MenuSelectedFcn",{@app.Delete_Structure,new_struct_props.id});
                    new_color_butt = uimenu(new_structure_context_menu,"Text","Change Color","MenuSelectedFcn",{@app.Change_Structure_Color,new_struct_props.id});
                    
                    new_structure_button.ContextMenu = new_structure_context_menu;
                    
                end

            end

        end
        
        function New_Annotation(app,src,event,structure_id)
            % Creating new ROI to add to the slide, Add 1 to table under
            % structure and next to the slide name
            structure_idx = app.Get_Structure_Idx(structure_id);

            element_idx = length(app.Structure_Properties(structure_idx).elements)+1;
            
            structure_color = app.Structure_Properties(structure_idx).color;
            roi = images.roi.Freehand(app.WSIAxes,...
                'Color',structure_color,...
                'FaceAlpha',0.2);
            roi.draw

            if isempty(app.Structure_Properties(structure_idx).elements)
                app.Structure_Properties(structure_idx).elements = [roi];
            else
                app.Structure_Properties(structure_idx).elements = [app.Structure_Properties(structure_idx).elements;roi];
            end            
            
            addlistener(roi,'ROIMoved',@(src,event)app.ROI_Change(src,event,structure_id,element_idx));
            addlistener(roi,'DeletingROI',@(src,event)app.ROI_Change(src,event,structure_id,element_idx));
            
            new_count = element_idx;
            app.Update_Annotation_Count(structure_id,new_count)

        end
        
        function Delete_Structure(app,src,event,structure_id)
             
            % Deleting a structure and it's button from app.StructuresPanel
            structure_idx = app.Get_Structure_Idx(structure_id);

            app.SlideTable.Data(:,1+structure_idx) = [];
            app.SlideTable.ColumnName(1+structure_idx) = [];

            delete(app.Structure_Properties(structure_idx).handle)
            for el=1:length(app.Structure_Properties(structure_idx).elements)
                delete(app.Structure_Properties(structure_idx).elements(el))
            end
            app.Structure_Properties(structure_idx) = [];

        end
        
        function Change_Structure_Color(app,src,event,structure_id)
            
            % Updating structure button and annotations
            structure_idx = app.Get_Structure_Idx(structure_id);
            new_color = [rand,rand,rand];
            app.Structure_Properties(structure_idx).color = new_color;

            app.Structure_Properties(structure_idx).handle.BackgroundColor = new_color;
            
            % Updating colors for all current annotations
            for el = 1:length(app.Structure_Properties(structure_idx).elements)
                app.Structure_Properties(structure_idx).elements(el).Color = new_color;
            end


        end
        
        function ROI_Change(app,src,event,structure_id,element_idx)
            % Callback for ROI being changed in any way
            event_name = event.EventName;
            structure_idx = app.Get_Structure_Idx(structure_id);

            switch(event_name)
                case('ROIMoved')
                    app.Structure_Properties(structure_idx).elements(element_idx) = event.Source;

                case('DeletingROI')
                    app.Structure_Properties(structure_idx).elements(element_idx) = [];
                    
                    % Updating number displayed in table
                    new_count = length(app.Structure_Properties(structure_idx).elements);
                    app.Update_Annotation_Count(structure_id,new_count)
            end
                    

        end
        
        function Update_Annotation_Count(app,structure_id,new_count)
            % Updating the slide table for a specfic structure

            % Getting current slide row:
            row_idx = app.Slide_Idx;

            % Getting current structure column:
            col_idx = app.Get_Structure_Idx(structure_id)+1;
            app.SlideTable.Data(row_idx,col_idx) = num2cell(new_count);
            
        end
        
        function structure_idx = Get_Structure_Idx(app,structure_id)
            % Get index corresonding to the queried structure id
            all_ids = cell(1,length(app.Structure_Properties));
            for s = 1:length(app.Structure_Properties)
                all_ids{1,s} = app.Structure_Properties(s).id;
            end

            structure_idx = find(strcmp(all_ids,structure_id));
            
        end
        
        function Format_Update(app,src,event,selected_format)
            % Makes this menu an exclusive selection
                
            other_options = get(app.WSIAxes.ContextMenu.Children(1),'Children');
            for i = 1:length(other_options)
                set(other_options(i),'Checked','off')
            end
            
            set(other_options(selected_format),'Checked','on')
            app.Annotation_Format = selected_format;

        end
        
        function Save_Current_Annotations(app,src,event)
            % Saving current annotations to the selected/default format
            
            if app.Annotation_Format==1
                app.Export_XML_Annotations()

            elseif app.Annotation_Format==2
                app.Export_GeoJSON_Annotations()

            elseif app.Annotation_Format==3
                app.Export_Histomics_Annotations()

            end

            
        end
        
        function Export_XML_Annotations(app)
            % Extracting annotated structures and saving in Aperio XML
            % format

            wb = waitbar(0,strcat('Saving annotations for:',app.Slide_List(app.Slide_Idx).name,', on:',app.Structure_Properties(1).name));

            xml_anns.Annotations.Annotation = cell(length(app.Structure_Properties),1);
            
            for a = 1:length(app.Structure_Properties)
                
                structure_ann.Attributes.Id = num2str(a);
                structure_ann.Attributes.DisplayId = num2str(a);
                structure_ann.Attributes.Name = app.Structure_Properties(a).name{1};
                
                for el = 1:length(app.Structure_Properties(a).elements)

                    % Updating waitbar
                    waitbar((a-1)+(el/length(app.Structure_Properties(a).elements)),wb,strcat('Saving annotations for:',app.Slide_List(app.Slide_Idx).name),', on:',app.Structure_Properties(a).name)
                    
                    % Extracting coordinates data from ROI handle
                    try
                        coords = app.Structure_Properties(a).elements(el).Position;
                    catch
                        display('ROI was deleted')
                        continue
                    end
                               
                    if length(coords)>=3
                        region_ann = [];
                        region_ann.Vertices = [];
    
                        for vert = 1:length(coords)
                            x = coords(vert,1);
                            y = coords(vert,2);
    
                            region_ann.Vertices.Vertex{1,vert}.Attributes.X = num2str(round(x));
                            region_ann.Vertices.Vertex{1,vert}.Attributes.Y = num2str(round(y));
                        end
    
                        region_ann.Vertices.Vertex{1,vert+1}.Attributes.X = num2str(round(coords(1,1)));
                        region_ann.Vertices.Vertex{1,vert+1}.Attributes.Y = num2str(round(coords(1,2)));
    
                        structure_ann.Regions.Region{el} = region_ann;
                    end

                end

                xml_anns.Annotations.Annotation{a} = structure_ann;

            end

            waitbar(1,wb,strcat('All done!'))
            pause(1)
            delete(wb)

            % Defining save path as the same as the WSI with the extension
            % replaced

            slide_ext = strsplit(app.Slide_List(app.Slide_Idx).name,'.');
            slide_ext = slide_ext{end};

            xml_filename = strrep(app.Slide_List(app.Slide_Idx).name,slide_ext,'xml');
            save_path = strcat(app.WSI_Folder,filesep,xml_filename);
            struct2xml(xml_anns,save_path)

        end
        
        function Export_GeoJSON_Annotations(app)

            wb = waitbar(0,strcat('Saving annotations for:',app.Slide_List(app.Slide_Idx).name,', on:',app.Structure_Properties(1).name));

            geojson_anns.type = "FeatureCollection";
            geojson_anns.features = [];

            for a = 1:length(app.Structure_Properties)
                structure_name = app.Structure_Properties(a).name{1};
                for el = 1:length(app.Structure_Properties(a).elements)
                    
                    % Updating waitbar
                    waitbar((a-1)+(el/length(app.Structure_Properties(a).elements)),wb,strcat('Saving annotations for:',app.Slide_List(app.Slide_Idx).name),', on:',app.Structure_Properties(a).name)
                    
                    try
                        coords = round(app.Structure_Properties(a).elements(el).Position);
                    catch
                        display('ROI was deleted')
                        continue
                    end
                    
                    if length(coords)>=3
                        coords_expanded = [];
                        coords_expanded(1,:,:) = coords;
                        coords_expanded(1,length(coords)+1,:) = coords(1,:);
    
                        new_feature.type = 'Feature';
                        new_feature.geometry.type = 'Polygon';
                        new_feature.geometry.coordinates = coords_expanded;
                        new_feature.properties.object_type = 'annotation';
                        new_feature.properties.classification.name = structure_name;                    
                        
                        geojson_anns.features{el} = new_feature;
                    end

                end
            end

            waitbar(1,wb,strcat('All done!'))
            pause(1)
            delete(wb)

            % Defining save path as the same as the WSI with the extension
            % replaced

            slide_ext = strsplit(app.Slide_List(app.Slide_Idx).name,'.');
            slide_ext = slide_ext{end};

            geojson_filename = strrep(app.Slide_List(app.Slide_Idx).name,slide_ext,'geojson');
            save_path = strcat(app.WSI_Folder,filesep,geojson_filename);
            file_id = fopen(save_path,'w');
            fprintf(file_id,'%s',jsonencode(geojson_anns,"PrettyPrint",true));
            fclose(file_id);
            
        end
        
        function Export_Histomics_Annotations(app)
            
            wb = waitbar(0,strcat('Saving annotations for:',app.Slide_List(app.Slide_Idx).name,', on:',app.Structure_Properties(1).name));

            histomics_anns = {};

            for a = 1:length(app.Structure_Properties)
                
                structure_name = app.Structure_Properties(a).name{1};
                
                structure.annotation.name = structure_name;
                structure.annotation.elements = {};

                structure_line_color = 255*app.Structure_Properties(a).color;
                structure_line_color = strcat('rgb(',num2str(structure_line_color(1)),',',num2str(structure_line_color(2)),',',num2str(structure_line_color(3)),')');
                
                for el = 1:length(app.Structure_Properties(a).elements)
                
                    % Updating waitbar
                    waitbar((a-1)+(el/length(app.Structure_Properties(a).elements)),wb,strcat('Saving annotations for:',app.Slide_List(app.Slide_Idx).name),', on:',app.Structure_Properties(a).name)
                    
                    try
                        coords = round(app.Structure_Properties(a).elements(el).Position);
                    catch
                        display('ROI was deleted')
                        continue
                    end
                    
                    if length(coords)>=3
                        coords = [coords,zeros(length(coords),1)];
                        coords_expanded = [];
                        coords_expanded(1,:,:) = coords;
                        coords_expanded(1,length(coords)+1,:) = coords(1,:);
                        
                        new_element.type = 'polyline';
                        new_element.closed = true;
                        new_element.points = coords_expanded;
                        new_element.id = strrep(char(matlab.lang.internal.uuid()),'-','');
                        new_element.lineColor = structure_line_color;
                        
                        structure.annotation.elements{el} = new_element;
                    end

                end

                histomics_anns{a} = structure;
            end

            waitbar(1,wb,strcat('All done!'))
            pause(1)
            delete(wb)

            % Defining save path as the same as the WSI with the extension
            % replaced

            slide_ext = strsplit(app.Slide_List(app.Slide_Idx).name,'.');
            slide_ext = slide_ext{end};

            histomics_filename = strrep(app.Slide_List(app.Slide_Idx).name,slide_ext,'json');
            save_path = strcat(app.WSI_Folder,filesep,histomics_filename);
            file_id = fopen(save_path,'w');
            fprintf(file_id,'%s',jsonencode(histomics_anns,"PrettyPrint",true));
            fclose(file_id);
            
        end
        
        function Check_Saved_Annotations(app)
            % Reminder prompt for the user to save annotations if not
            % already saved.
                    
            current_slide = app.Slide_List(app.Slide_Idx).name;
            slide_ext = strsplit(current_slide,'.');
            slide_ext = slide_ext{end};

            annotations_path = strcat(app.WSI_Folder,filesep,current_slide);
            if app.Annotation_Format==1
                annotations_path = strrep(annotations_path,slide_ext,'xml');
            elseif app.Annotation_Format==2
                annotations_path = strrep(annotations_path,slide_ext,'geojson');
            elseif app.Annotation_Format==3
                annotations_path = strrep(annotations_path,slide_ext,'json');
            end

            if ~isfile(annotations_path)
                question = strcat('Would you like to save your annotations before moving on to the next slide?');
                question_title = 'Save Annotations';

                response = questdlg(question,question_title);

                switch response
                    case 'Yes'

                        app.Save_Current_Annotations([])

                end

            end

            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, wsi_folder)
            
            % Starting application with a folder of WSIs:
            if ~isempty(wsi_folder)
            
                app.WSI_Folder = wsi_folder;
                app.SelectFolderButton.Enable = 'off';

                app.WSIFolderLabel.Text = strcat('WSI Folder:',app.WSI_Folder);
    
                app_children = get(app.WSIAnnotationUIFigure,'Children');
                set(app_children(isprop(app_children,'Enable')),'Enable','on')
    
                wsi_folder_contents = dir(app.WSI_Folder);
                wsi_folder_contents = wsi_folder_contents(~ismember({wsi_folder_contents.name},{'.','..'}));
                wsi_folder_contents = wsi_folder_contents(contains({wsi_folder_contents.name},app.WSI_Formats));
                
                app.Slide_List = wsi_folder_contents;
                app.Slide_Idx = 1;
    
    
                app.SlideTable.Data = {app.Slide_List.name}';
                app.SlideTable.ColumnName = {'Slide Name'};
    
                % Adding context menu to UIAxes
                save_context_menu = uicontextmenu("Parent",app.WSIAnnotationUIFigure);
                save_option = uimenu(save_context_menu,'Text','Save','Accelerator','S','MenuSelectedFcn',@app.Save_Current_Annotations);
                format_option = uimenu(save_context_menu,'Text','Annotation Format');
                % For some reason these are reversed in order when assigned to
                % children of the save_context_menu
                aperio_option = uimenu(format_option,'Text','Aperio ImageScope (XML)','Checked','on','Tag','AperioFormat','MenuSelectedFcn',{@app.Format_Update,3});
                geojson_option = uimenu(format_option,'Text','GeoJSON (JSON)','Checked','off','Tag','GeoJSONFormat','MenuSelectedFcn',{@app.Format_Update,2});
                histomics_option = uimenu(format_option,'Text','Histomics (JSON)','Checked','off','Tag','HistomicsFormat','MenuSelectedFcn',{@app.Format_Update,1});
    
                app.WSIAxes.ContextMenu = save_context_menu;
    
                % Adding new structure button to structures panel
                new_structure_button = uibutton(app.GridLayout5,'push');
                new_structure_button.Enable = 'on';
                new_structure_button.Layout.Row = 1;
                new_structure_button.Layout.Column = 1;
                new_structure_button.Text = "New Structure";
                new_structure_button.FontWeight = 'bold';
                new_structure_button.ButtonPushedFcn = {@app.Add_New_Structure,"New Structure"};
    
                app.Current_Img = blockedImage(strcat(app.WSI_Folder,filesep,wsi_folder_contents(1).name));
                
                New_Image_View(app)

            end


        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            
            app.WSI_Folder = uigetdir(pwd,"Select folder of WSIs");
            app.WSIFolderLabel.Text = strcat('WSI Folder:',app.WSI_Folder);

            app_children = get(app.WSIAnnotationUIFigure,'Children');
            set(app_children(isprop(app_children,'Enable')),'Enable','on')

            wsi_folder_contents = dir(app.WSI_Folder);
            wsi_folder_contents = wsi_folder_contents(~ismember({wsi_folder_contents.name},{'.','..'}));
            wsi_folder_contents = wsi_folder_contents(contains({wsi_folder_contents.name},app.WSI_Formats));
            
            app.Slide_List = wsi_folder_contents;
            app.Slide_Idx = 1;


            app.SlideTable.Data = {app.Slide_List.name}';
            app.SlideTable.ColumnName = {'Slide Name'};

            % Adding context menu to UIAxes
            save_context_menu = uicontextmenu("Parent",app.WSIAnnotationUIFigure);
            save_option = uimenu(save_context_menu,'Text','Save','Accelerator','S','MenuSelectedFcn',@app.Save_Current_Annotations);
            format_option = uimenu(save_context_menu,'Text','Annotation Format');
            % For some reason these are reversed in order when assigned to
            % children of the save_context_menu
            aperio_option = uimenu(format_option,'Text','Aperio ImageScope (XML)','Checked','on','Tag','AperioFormat','MenuSelectedFcn',{@app.Format_Update,3});
            geojson_option = uimenu(format_option,'Text','GeoJSON (JSON)','Checked','off','Tag','GeoJSONFormat','MenuSelectedFcn',{@app.Format_Update,2});
            histomics_option = uimenu(format_option,'Text','Histomics (JSON)','Checked','off','Tag','HistomicsFormat','MenuSelectedFcn',{@app.Format_Update,1});

            app.WSIAxes.ContextMenu = save_context_menu;

            % Adding new structure button to structures panel
            new_structure_button = uibutton(app.GridLayout5,'push');
            new_structure_button.Enable = 'on';
            new_structure_button.Layout.Row = 1;
            new_structure_button.Layout.Column = 1;
            new_structure_button.Text = "New Structure";
            new_structure_button.FontWeight = 'bold';
            new_structure_button.ButtonPushedFcn = {@app.Add_New_Structure,"New Structure"};

            app.Current_Img = blockedImage(strcat(app.WSI_Folder,filesep,wsi_folder_contents(1).name));
            
            New_Image_View(app)


        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            % Getting the next slide in the list
            
            app.Check_Saved_Annotations()

            if app.Slide_Idx+1>length(app.Slide_List)
                app.Slide_Idx = 1;
            else
                app.Slide_Idx = app.Slide_Idx + 1;
            end

            app.Current_Img = blockedImage(strcat(app.WSI_Folder,filesep,app.Slide_List(app.Slide_Idx).name));

            New_Image_View(app)


        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            % Updating current slide to the previous one in the list
            
            app.Check_Saved_Annotations()

            if app.Slide_Idx-1<1
                app.Slide_Idx = length(app.Slide_List);
            else
                app.Slide_Idx = app.Slide_Idx - 1;
            end

            app.Current_Img = blockedImage(strcat(app.WSI_Folder,filesep,app.Slide_List(app.Slide_Idx).name));

            New_Image_View(app)

        end

        % Cell selection callback: SlideTable
        function SlideTableCellSelection(app, event)
            % Updating current slide when name is clicked in slide table
            
            app.Check_Saved_Annotations()

            indices = event.Indices;
            
            app.Slide_Idx = indices(1);

            app.Current_Img = blockedImage(strcat(app.WSI_Folder,filesep,app.Slide_List(app.Slide_Idx).name));

            New_Image_View(app)

        end

        % Key press function: WSIAnnotationUIFigure
        function WSIAnnotationUIFigureKeyPress(app, event)
            % Make it so you can add annotations from the keyboard
            key = event.Key;
            
            if isempty(event.Modifier)
                if ~isnan(str2double(key))
                    if length(app.Structure_Properties)>=str2double(key)
                        app.New_Annotation(app,[],app.Structure_Properties(str2double(key)).id)
                    else
                        msgbox('Uh oh! Add more annotations to use that key!')
                    end
                end
            elseif isequal(event.Modifier{1},'control')
                if strcmp(key,'n')
                    app.Add_New_Structure([],[],'New Structure')
                end
            
            elseif isequal(event.Modifier{1},'shift')
                if ~isnan(str2double(key))
                    if length(app.Structure_Properties)>=str2double(key)
                        app.Change_Structure_Color(app,[],app.Structure_Properties(str2double(key)).id)
                    else
                        msgbox('Uh oh! Add more annotations to use that key!')
                    end
                end
            end


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.WSIAnnotationUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {549, 549};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {226, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WSIAnnotationUIFigure = uifigure('Visible', 'off');
            app.WSIAnnotationUIFigure.Name = 'WSI Annotation';
            app.WSIAnnotationUIFigure.Icon = 'Logo.png';
            app.WSIAnnotationUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.WSIAnnotationUIFigure.KeyPressFcn = createCallbackFcn(app, @WSIAnnotationUIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.WSIAnnotationUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.HistoLensWSIAnnotationLabel = uilabel(app.LeftPanel);
            app.HistoLensWSIAnnotationLabel.Text = 'HistoLens WSI Annotation';
            app.SlideTable = uitable(app.LeftPanel);
            app.SlideTable.ColumnName = {'Slide Name'};
            app.SlideTable.RowName = {};
            app.SlideTable.CellSelectionCallback = createCallbackFcn(app, @SlideTableCellSelection, true);
            app.StructuresPanel = uipanel(app.LeftPanel);
            app.StructuresPanel.Title = 'Structures';
            app.GridLayout5 = uigridlayout(app.StructuresPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.WSIAxes = uiaxes(app.GridLayout2);
            title(app.WSIAxes, 'WSI Annotation')
            app.WSIAxes.XTick = [];
            app.WSIAxes.YTick = [];
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.WSIFolderLabel = uilabel(app.GridLayout3);
            app.WSIFolderLabel.Text = 'WSI Folder: ';
            app.SelectFolderButton = uibutton(app.GridLayout3, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Folder';
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.PreviousButton = uibutton(app.GridLayout4, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Icon = 'PrevButt.png';
            app.PreviousButton.Text = '';
            app.NextButton = uibutton(app.GridLayout4, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Icon = 'NextButt.png';
            app.NextButton.Text = '';
            % Show the figure after all components are created
            app.WSIAnnotationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HistoLens_WSI_Annotation(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WSIAnnotationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WSIAnnotationUIFigure)
        end
    end
end